classdef seird_lq_ayan_dhruv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MainTab                    matlab.ui.container.Tab
        AVisualStudyofPerturbationTheoryLabel  matlab.ui.control.Label
        AyanAhmad2K19EP027DhruvTyagi2K19EP032Label_3  matlab.ui.control.Label
        DepartmentofAppliedPhysicsDelhiTechnologicalUniversityLabel_3  matlab.ui.control.Label
        Image_3                    matlab.ui.control.Image
        PerturbationDropDownLabel  matlab.ui.control.Label
        PerturbationDropDown       matlab.ui.control.DropDown
        UIAxes_perturbation        matlab.ui.control.UIAxes
        v0slider                   matlab.ui.control.Slider
        V0Label                    matlab.ui.control.Label
        UIAxes_probden             matlab.ui.control.UIAxes
        UIAxes_correction          matlab.ui.control.UIAxes
        energylevelslider          matlab.ui.control.Slider
        EnergyLevelLabel           matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        UIAxes_e1                  matlab.ui.control.UIAxes
        UIAxes_e4                  matlab.ui.control.UIAxes
        UIAxes_e3                  matlab.ui.control.UIAxes
        UIAxes_e2                  matlab.ui.control.UIAxes
        UpdateButton               matlab.ui.control.Button
        RunButton                  matlab.ui.control.Button
        PerturbationTab            matlab.ui.container.Tab
        UIAxes_per1                matlab.ui.control.UIAxes
        PerturbationLabel          matlab.ui.control.Label
        UIAxes_per4                matlab.ui.control.UIAxes
        UIAxes_per3                matlab.ui.control.UIAxes
        UIAxes_per2                matlab.ui.control.UIAxes
        UIAxes_per5                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            x=0:1;
            p1=piecewise(x<=0.5, 1, x>0.5, 0);
            p2=piecewise(x<=0, 0,  (0.4<=x) && (x<=0.6), 1, x>0.6, 0);
            p3=piecewise(-x+0.4,x);
            p4=piecewise(x<=0, 1, (0.25<=x) && (x<=0.75), -1, x>0.75, 1);
            p5=piecewise(x<=0.5, -x+0.4, x>0.5, x-0.4);
         
            plot(app.UIAxes_per1,p1,x)
            plot(app.UIAxes_per1,p2,x)
            plot(app.UIAxes_per1,p3,x)
            plot(app.UIAxes_per1,p4,x)
            plot(app.UIAxes_per1,p5,x)
        end

        % Callback function
        function DefaultButtonPushed(app, event)
            
            %% DEFAULT SETTING
            % Puts all the switches in 'off' configuration and that triggers the update button which causes all the sliders to take default value as in library section.
            
            app.BRNSwitch.Value='Off';
            app.DincSwitch.Value='Off';
            app.DinfSwitch.Value='Off';
            app.FatalitySwitch.Value='Off';
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            x=0:1;
            p1=piecewise(x<=0.5, 1, x>0.5, 0);
            p2=piecewise(x<=0, 0,  (0.4<=x) && (x<=0.6), 1, x>0.6, 0);
            p3=piecewise(-x+0.4,x);
            p4=piecewise(x<=0, 1, (0.25<=x) && (x<=0.75), -1, x>0.75, 1);
            p5=piecewise(x<=0.5, -x+0.4, x>0.5, x-0.4);
         
            plot(app.UIAxes_per1,p1,x)
            plot(app.UIAxes_per1,p2,x)
            plot(app.UIAxes_per1,p3,x)
            plot(app.UIAxes_per1,p4,x)
            plot(app.UIAxes_per1,p5,x)
        end

        % Callback function
        function UpdateButtonPushed2(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.AVisualStudyofPerturbationTheoryLabel = uilabel(app.MainTab);
            app.AVisualStudyofPerturbationTheoryLabel.Text = 'A Visual Study of Perturbation Theory';
            app.AyanAhmad2K19EP027DhruvTyagi2K19EP032Label_3 = uilabel(app.MainTab);
            app.AyanAhmad2K19EP027DhruvTyagi2K19EP032Label_3.Text = 'Ayan Ahmad (2K19/EP/027) & Dhruv Tyagi (2K19/EP/032)';
            app.DepartmentofAppliedPhysicsDelhiTechnologicalUniversityLabel_3 = uilabel(app.MainTab);
            app.DepartmentofAppliedPhysicsDelhiTechnologicalUniversityLabel_3.Text = 'Department of Applied Physics, Delhi Technological University';
            app.Image_3 = uiimage(app.MainTab);
            app.PerturbationDropDownLabel = uilabel(app.MainTab);
            app.PerturbationDropDownLabel.Text = 'Perturbation';
            app.PerturbationDropDown = uidropdown(app.MainTab);
            app.PerturbationDropDown.Items = {'1', '2', '3', '4', '5'};
            app.PerturbationDropDown.Value = '1';
            app.UIAxes_perturbation = uiaxes(app.MainTab);
            title(app.UIAxes_perturbation, 'Perturbation')
            xlabel(app.UIAxes_perturbation, 'x (L)')
            ylabel(app.UIAxes_perturbation, 'V (V0)')
            app.v0slider = uislider(app.MainTab);
            app.v0slider.Limits = [-1 1];
            app.V0Label = uilabel(app.MainTab);
            app.V0Label.Text = 'V0';
            app.UIAxes_probden = uiaxes(app.MainTab);
            title(app.UIAxes_probden, 'Probability Density')
            xlabel(app.UIAxes_probden, 'x (L)')
            ylabel(app.UIAxes_probden, 'V (V0)')
            app.UIAxes_correction = uiaxes(app.MainTab);
            title(app.UIAxes_correction, 'First Order Correction')
            xlabel(app.UIAxes_correction, 'x (L)')
            ylabel(app.UIAxes_correction, 'V (V0)')
            app.energylevelslider = uislider(app.MainTab);
            app.energylevelslider.Limits = [1 4];
            app.energylevelslider.MajorTicks = [1 2 3 4];
            app.energylevelslider.Value = 1;
            app.EnergyLevelLabel = uilabel(app.MainTab);
            app.EnergyLevelLabel.Text = 'Energy Level';
            app.EditField = uieditfield(app.MainTab, 'text');
            app.UIAxes_e1 = uiaxes(app.MainTab);
            title(app.UIAxes_e1, 'E1')
            xlabel(app.UIAxes_e1, '')
            ylabel(app.UIAxes_e1, 'E(1)')
            app.UIAxes_e4 = uiaxes(app.MainTab);
            title(app.UIAxes_e4, 'E4')
            xlabel(app.UIAxes_e4, '')
            ylabel(app.UIAxes_e4, 'E(1)')
            app.UIAxes_e3 = uiaxes(app.MainTab);
            title(app.UIAxes_e3, 'E3')
            xlabel(app.UIAxes_e3, '')
            ylabel(app.UIAxes_e3, 'E(1)')
            app.UIAxes_e2 = uiaxes(app.MainTab);
            title(app.UIAxes_e2, 'E2')
            xlabel(app.UIAxes_e2, '')
            ylabel(app.UIAxes_e2, 'E(1)')
            app.UpdateButton = uibutton(app.MainTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.RunButton = uibutton(app.MainTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PerturbationTab = uitab(app.TabGroup);
            app.PerturbationTab.Title = 'Perturbation';
            app.UIAxes_per1 = uiaxes(app.PerturbationTab);
            title(app.UIAxes_per1, '1')
            xlabel(app.UIAxes_per1, 'x (L)')
            ylabel(app.UIAxes_per1, 'V (V0)')
            app.PerturbationLabel = uilabel(app.PerturbationTab);
            app.PerturbationLabel.Text = 'Perturbation';
            app.UIAxes_per4 = uiaxes(app.PerturbationTab);
            title(app.UIAxes_per4, '4')
            xlabel(app.UIAxes_per4, 'x (L)')
            ylabel(app.UIAxes_per4, 'V (V0)')
            app.UIAxes_per3 = uiaxes(app.PerturbationTab);
            title(app.UIAxes_per3, '3')
            xlabel(app.UIAxes_per3, 'x (L)')
            ylabel(app.UIAxes_per3, 'V (V0)')
            app.UIAxes_per2 = uiaxes(app.PerturbationTab);
            title(app.UIAxes_per2, '2')
            xlabel(app.UIAxes_per2, 'x (L)')
            ylabel(app.UIAxes_per2, 'V (V0)')
            app.UIAxes_per5 = uiaxes(app.PerturbationTab);
            title(app.UIAxes_per5, '5')
            xlabel(app.UIAxes_per5, 'x (L)')
            ylabel(app.UIAxes_per5, 'V (V0)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seird_lq_ayan_dhruv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end